classdef croppingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Switch       matlab.ui.control.Switch
        SwitchLabel  matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        newIm,value,path,croppedImage,file, total,type_of_file,number_of_image,imageraw,string,out,fileName,namefile
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
          app.value = app.Switch.Value;
          [app.file,app.path] = uigetfile;
          app.type_of_file = '.jpg';
          app.total = dir([app.path,'*',app.type_of_file]);
          app.number_of_image = size(app.total,1);
          mkdir app.path Cropped
          for k=1:app.number_of_image
                  app.string = [app.path,app.total(k,1).name];
                  app.imageraw=imread(app.string);
                  %[app.Image,~] = imcrop(app.imageraw);
                  [app.newIm,~]=imcrop(app.imageraw);
                  app.namefile="cropped"+k+".jpg";
                  imwrite(app.newIm,app.namefile)
                  imwrite(app.newIm, fullfile(app.path,app.namefile))
                   
          end
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = croppingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end